classdef login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        registerIUi     matlab.ui.Figure
        EditField       matlab.ui.control.NumericEditField
        EditFieldLabel  matlab.ui.control.Label
        Button          matlab.ui.control.Button
        Password        matlab.ui.control.NumericEditField
        Label_2         matlab.ui.control.Label
        Account         matlab.ui.control.NumericEditField
        Label           matlab.ui.control.Label
        COMSOL_Icon     matlab.ui.control.Image
        background      matlab.ui.control.Label
        Image           matlab.ui.control.Image
    end

    
    properties (Access = public)
        Login_Account
        Login_Password % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            if app.Login_Account==1219196913 && app.Login_Password==100200300
                delete(app.registerIUi);
                %open('D:\Softwares\COMSOL56\Multiphysics\bin\win64\comsolmphserver.exe')
                run Calculate.mlapp;
                
            else
                errordlg('账号或密码错误','Error','modal');
            end
        end

        % Value changed function: Password
        function PasswordValueChanged(app, event)
            
            TEMP=app.EditField.Value;
            
            
            %app.Login_Password = app.Password.Value;
        end

        % Value changed function: Account
        function AccountValueChanged(app, event)
            app.Login_Account = app.Account.Value;
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            password=sprintf('%s', app.Login_Password);
            app.EditField=uieditfield(app.registerIUi, ...
                "Value",password, ...
                "HorizontalAlignment","left", ...
                "Editable","on","Position",[140 59 100 22], ...
                "ValueChangedFcn",@PasswordValueChanged);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.registerIUi = uifigure('Visible', 'off');
            app.registerIUi.Name = 'MATLAB App';
            app.Image = uiimage(app.registerIUi);
            app.Image.ImageSource = 'Snipaste_2021-09-18_10-57-54.png';
            app.background = uilabel(app.registerIUi);
            app.background.Text = '';
            app.COMSOL_Icon = uiimage(app.registerIUi);
            app.COMSOL_Icon.ImageSource = 'comsol.jpg';
            app.Label = uilabel(app.registerIUi);
            app.Label.Tooltip = {'请输入账号'};
            app.Label.Text = '账号';
            app.Account = uieditfield(app.registerIUi, 'numeric');
            app.Account.ValueChangedFcn = createCallbackFcn(app, @AccountValueChanged, true);
            app.Label_2 = uilabel(app.registerIUi);
            app.Label_2.Tooltip = {'请输入密码'};
            app.Label_2.Text = '密码';
            app.Password = uieditfield(app.registerIUi, 'numeric');
            app.Password.ValueChangedFcn = createCallbackFcn(app, @PasswordValueChanged, true);
            app.Button = uibutton(app.registerIUi, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '登录';
            app.EditFieldLabel = uilabel(app.registerIUi);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.registerIUi, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.registerIUi.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.registerIUi)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.registerIUi)
        end
    end
end