classdef login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        registerIUi     matlab.ui.Figure
        close           matlab.ui.control.Button
        enter           matlab.ui.control.Button
        Password        matlab.ui.control.NumericEditField
        Label_Password  matlab.ui.control.Label
        Account         matlab.ui.control.NumericEditField
        Label_Account   matlab.ui.control.Label
        COMSOL_Icon     matlab.ui.control.Image
        background1     matlab.ui.control.Label
        background2     matlab.ui.control.Image
    end

    
    properties (Access = public)%全局变量
        Login_Account % 账户
        Login_Password % 密码
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: enter
        function enterPushed(app, event)
            
            if app.Login_Account==12 && app.Login_Password==100200300
                delete(app.registerIUi);
                open('D:\All Softwares\COMSOL56\Multiphysics\bin\win64\comsolmphserver.exe')
                run ('D:\GitHub\paper1\Calculate.mlapp');
            else
                errordlg('账号或密码错误','Error','modal');
            end
        end

        % Value changed function: Password
        function PasswordValueChanged(app, event)
            %全局变量密码赋值
            app.Login_Password = app.Password.Value;
        end

        % Value changed function: Account
        function AccountValueChanged(app, event)
            %全局变量账户赋值
            app.Login_Account = app.Account.Value;
            
        end

        % Button pushed function: close
        function closeButtonPushed(app, event)
            delete(app.registerIUi);
        end

        % Window key press function: registerIUi
        function registerIUiWindowKeyPress(app, event)

            %监听键盘
            key = event.Key;
            %考虑使用switch语句进行判断
            switch key
                case "return"
                    enterPushed(app, event);    
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.registerIUi = uifigure('Visible', 'off');
            app.registerIUi.Name = 'COMSOL With MATLAB';
            app.registerIUi.Icon = 'comsol.jpg';
            app.registerIUi.WindowKeyPressFcn = createCallbackFcn(app, @registerIUiWindowKeyPress, true);
            app.background2 = uiimage(app.registerIUi);
            app.background2.ImageSource = 'Snipaste_2021-09-18_10-57-54.png';
            app.background1 = uilabel(app.registerIUi);
            app.background1.Text = '';
            app.COMSOL_Icon = uiimage(app.registerIUi);
            app.COMSOL_Icon.ImageSource = 'comsol.jpg';
            app.Label_Account = uilabel(app.registerIUi);
            app.Label_Account.Tooltip = {'请输入账号'};
            app.Label_Account.Text = '账号';
            app.Account = uieditfield(app.registerIUi, 'numeric');
            app.Account.ValueChangedFcn = createCallbackFcn(app, @AccountValueChanged, true);
            app.Label_Password = uilabel(app.registerIUi);
            app.Label_Password.Tooltip = {'请输入密码'};
            app.Label_Password.Text = '密码';
            app.Password = uieditfield(app.registerIUi, 'numeric');
            app.Password.ValueChangedFcn = createCallbackFcn(app, @PasswordValueChanged, true);
            app.enter = uibutton(app.registerIUi, 'push');
            app.enter.ButtonPushedFcn = createCallbackFcn(app, @enterPushed, true);
            app.enter.Text = '登录';
            app.close = uibutton(app.registerIUi, 'push');
            app.close.ButtonPushedFcn = createCallbackFcn(app, @closeButtonPushed, true);
            app.close.Icon = 'close.png';
            app.close.Text = '';
            % Show the figure after all components are created
            app.registerIUi.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.registerIUi)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.registerIUi)
        end
    end
end